classdef demo_app_sericom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mlyUIFigure           matlab.ui.Figure
        Menu                  matlab.ui.container.Menu
        Menu_3                matlab.ui.container.Menu
        Menu_2                matlab.ui.container.Menu
        Menu_4                matlab.ui.container.Menu
        Panel                 matlab.ui.container.Panel
        Panel_2               matlab.ui.container.Panel
        Lamp_seriComStatus    matlab.ui.control.Lamp
        Button_OpenSeriCom    matlab.ui.control.StateButton
        Label                 matlab.ui.control.Label
        DropDown_SeriComName  matlab.ui.control.DropDown
        Label_2               matlab.ui.control.Label
        DropDown_BaudRate     matlab.ui.control.DropDown
        Label_3               matlab.ui.control.Label
        DropDown_DataBits     matlab.ui.control.DropDown
        Label_4               matlab.ui.control.Label
        DropDown_Parity       matlab.ui.control.DropDown
        Label_5               matlab.ui.control.Label
        DropDown_StopBits     matlab.ui.control.DropDown
        CheckBox_HexDisp      matlab.ui.control.CheckBox
        CheckBox_TimeVisible  matlab.ui.control.CheckBox
        Panel_6               matlab.ui.container.Panel
        TextArea_Send         matlab.ui.control.TextArea
        Button_send           matlab.ui.control.Button
        Label_9               matlab.ui.control.Label
        CheckBox_Hexsend      matlab.ui.control.CheckBox
        Panel_7               matlab.ui.container.Panel
        TextArea_Receive_log  matlab.ui.control.TextArea
        ContextMenu           matlab.ui.container.ContextMenu
        Menu_5                matlab.ui.container.Menu
        Menu2                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        StrArray % 日志时间戳 信息
        sObject % 串口
    end
    
    methods (Access = private)
        function logRefresh_func(app,StrArrayNew)
            %刷日志+时间戳
            if length(app.StrArray)>=30
                app.StrArray={};
            end
            temp=cellstr(datetime);
            app.StrArray=[app.StrArray,StrArrayNew,temp{1}];
            app.TextArea_Receive_log.Value=app.StrArray;
        end
        
        
        function  serialreadCallback(app,sObjtemp,~)
            %接收显示
            a=read(sObjtemp,10,'string');
            disp(a);
            StrArrayNew={['->',dec2hex(a')]};
            logRefresh_func(app,StrArrayNew);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %startup
            %seriallist
            ss=serialportlist;
            if  ~isempty(ss)
                app.DropDown_SeriComName.Items=ss;
            else
                app.DropDown_SeriComName.Items={'COM1'};
                msgbox('检测发现：无串口端口号!','error:' );
            end
            
        end

        % Value changed function: Button_OpenSeriCom
        function Button_OpenSeriComValueChanged(app, event)
            value = app.Button_OpenSeriCom.Value;
            %%创建串口以及判断串口状态
            switch value
                case 1
                    %串口创建
                    delete(instrfindall);%注销系统之前已经打开的串口资源
                    % close(app.sObject);
                    % app.sObject.Status = close;
                    try
                        seriComName=app.DropDown_SeriComName.Value;
                        app.sObject=serialport(seriComName,str2double(app.DropDown_BaudRate.Value), ...
                            'Parity',app.DropDown_Parity.Value, ...
                            'DataBits',str2double(app.DropDown_DataBits.Value), ...
                            'StopBits',str2double(app.DropDown_StopBits.Value));%创建串口 %特别注意 这里的数值参数必须转换为double类型
                        app.Lamp_seriComStatus.Color="red";
                        app.Button_OpenSeriCom.Text="关闭串口";
                    catch
                        msgbox('该串口可能被别的应用占用或串口命名错误！','error:' );
                        app.Button_OpenSeriCom.Value = 0;
                        app.Lamp_seriComStatus.Color="black";
                        app.Button_OpenSeriCom.Text="打开串口";
                        return;
                    end
                    configureCallback(app.sObject,"byte",1,@app.serialreadCallback);
                    
                case 0
                    %断开串口
                    try
                        app.sObject=[];
                        app.Lamp_seriComStatus.Color="Black";
                        app.Button_OpenSeriCom.Text="打开串口";
                    catch
                        msgbox('关闭串口失败','error');
                        app.Button_OpenSeriCom.Value = 1;
                    end
                    
            end
        end

        % Button pushed function: Button_send
        function Button_sendPushed(app, event)
            
            %%串口发送模块
            %检查端口是否在线--串口
            if isempty(app.sObject)
                msgbox('串口未打开','error');
                return
            elseif isempty(app.TextArea_Send.Value{1})
                msgbox('发送区为空','error');
                return
                
            end
            temp=app.TextArea_Send.Value;
            if app.CheckBox_Hexsend.Value ==1
                decData = sscanf(char(temp),'%x');                
                write(app.sObject,decData,'uint8'); % 以二进制形式向obj写入数据dataSend
            else
                writeline(app.sObject,char(temp) );
            end
            %             %按照空格键将字符串拆分
            %             %将1*1的字符串数组拆分成1*n的字符串数组
            %             temp=cellstr(app.TextArea_Send.Value);
            %             C=strsplit(temp{1});
            %             nn=length(C);
            %             decData=zeros(1,nn);
            %             for ii=1:nn
            %                  decData(ii)=hex2dec(C(ii));
            %             end
            %             %开始发送
            %
            %             write(app.sObject, decData,'uint8'); % 以二进制形式向obj写入数据dataSend
            %             %发送完成，日志显示
            %             StrArrayNew={'<-',temp{1}};
            %             logRefresh_func(app,StrArrayNew);
            
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            %版本信息
            StrArrayNew={'版本信息:2021-04-26;MLY'};
            logRefresh_func(app,StrArrayNew);
        end

        % Close request function: mlyUIFigure
        function mlyUIFigureCloseRequest(app, event)
            delete(app)
            try
                stopasync(app.sObject);%Stop asynchronous read and write operations
                fclose(app.sObject);%关闭app.sObject句柄
                delete(app.sObject);%删除app.sObject句柄
            catch
                
            end
        end

        % Callback function
        function ButtonPushed(app, event)
            disp('BytesAvailable=');
            disp(app.sObject.BytesAvailable);
            
            
        end

        % Callback function
        function Button_2Pushed(app, event)
            disp('BytesAvailable=');
            disp(app.sObject.BytesAvailable);
            fread(app.sObject,2,'uint8');
            %             fread(app.sObject,40,'uint8');
            disp('BytesAvailable=');
            disp(app.sObject.BytesAvailable);
            
        end

        % Callback function
        function DropDown_SeriComNameOpening(app, event)
            ss=seriallist;
            if  ~isempty(ss)
                app.DropDown_SeriComName.Items=ss;
            else
                app.DropDown_SeriComName.Items={'COM1'};
                msgbox('msg: 检测发现：无串口端口号!','error');
            end
        end

        % Value changed function: TextArea_Send
        function TextArea_SendValueChanged(app, event)
            value = app.TextArea_Send.Value;
            C = length(value);
            if app.CheckBox_Hexsend.Value ==1 && mod(length(value),2)==0
                app.TextArea_Send.Value = app.TextArea_Send.Value+' ';
            end
        end

        % Value changed function: CheckBox_Hexsend
        function CheckBox_HexsendValueChanged(app, event)
            temp = char(app.TextArea_Send.Value);
            if app.CheckBox_Hexsend.Value==1
                Hex_data = sprintf("%x ",double(temp));
                app.TextArea_Send.Value = Hex_data;
               % C = strsplit(temp);
               %app.TextArea_Send.Value = sscanf(str(temp),'%x');   
            else
                Char_Data = char(sscanf(char(temp),'%x'))'; 
                app.TextArea_Send.Value = Char_Data;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mlyUIFigure = uifigure('Visible', 'off');
            app.mlyUIFigure.Name = '自编串口助手mly';
            app.mlyUIFigure.CloseRequestFcn = createCallbackFcn(app, @mlyUIFigureCloseRequest, true);
            app.Menu = uimenu(app.mlyUIFigure);
            app.Menu.Text = '串口助手主界面-';
            app.Menu_3 = uimenu(app.mlyUIFigure);
            app.Menu_3.Text = '设置-';
            app.Menu_2 = uimenu(app.mlyUIFigure);
            app.Menu_2.Text = '帮助-';
            app.Menu_4 = uimenu(app.Menu_2);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '版本信息';
            app.Panel = uipanel(app.mlyUIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Title = '串口设置';
            app.Lamp_seriComStatus = uilamp(app.Panel_2);
            app.Button_OpenSeriCom = uibutton(app.Panel_2, 'state');
            app.Button_OpenSeriCom.ValueChangedFcn = createCallbackFcn(app, @Button_OpenSeriComValueChanged, true);
            app.Button_OpenSeriCom.Text = '打开串口';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '串口号：';
            app.DropDown_SeriComName = uidropdown(app.Panel_2);
            app.DropDown_SeriComName.Items = {'COM1', 'COM2'};
            app.DropDown_SeriComName.Value = 'COM1';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '波特率：';
            app.DropDown_BaudRate = uidropdown(app.Panel_2);
            app.DropDown_BaudRate.Items = {'9600', '38400', '115200'};
            app.DropDown_BaudRate.Value = '115200';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '数据位：';
            app.DropDown_DataBits = uidropdown(app.Panel_2);
            app.DropDown_DataBits.Items = {'8', '7', '6', '5'};
            app.DropDown_DataBits.Value = '8';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '校验位：';
            app.DropDown_Parity = uidropdown(app.Panel_2);
            app.DropDown_Parity.Items = {'none', 'even', 'odd'};
            app.DropDown_Parity.Value = 'none';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '停止位：';
            app.DropDown_StopBits = uidropdown(app.Panel_2);
            app.DropDown_StopBits.Items = {'1', '1.5', '2'};
            app.DropDown_StopBits.Value = '1';
            app.CheckBox_HexDisp = uicheckbox(app.Panel_2);
            app.CheckBox_HexDisp.Text = '十六进制显示';
            app.CheckBox_TimeVisible = uicheckbox(app.Panel_2);
            app.CheckBox_TimeVisible.Text = '时间显示';
            app.Panel_6 = uipanel(app.Panel);
            app.Panel_6.Title = '发送区';
            app.TextArea_Send = uitextarea(app.Panel_6);
            app.TextArea_Send.ValueChangedFcn = createCallbackFcn(app, @TextArea_SendValueChanged, true);
            app.TextArea_Send.Tooltip = {''};
            app.TextArea_Send.Value = {'hel'};
            app.Button_send = uibutton(app.Panel_6, 'push');
            app.Button_send.ButtonPushedFcn = createCallbackFcn(app, @Button_sendPushed, true);
            app.Button_send.Text = '发送';
            app.Label_9 = uilabel(app.Panel_6);
            app.Label_9.Text = '';
            app.CheckBox_Hexsend = uicheckbox(app.Panel_6);
            app.CheckBox_Hexsend.ValueChangedFcn = createCallbackFcn(app, @CheckBox_HexsendValueChanged, true);
            app.CheckBox_Hexsend.Text = '十六进制发送';
            app.Panel_7 = uipanel(app.Panel);
            app.Panel_7.Title = '接收区';
            app.TextArea_Receive_log = uitextarea(app.Panel_7);
            app.ContextMenu = uicontextmenu(app.mlyUIFigure);
            app.Menu_5 = uimenu(app.ContextMenu);
            app.Menu_5.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.mlyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_app_sericom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mlyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mlyUIFigure)
        end
    end
end